classdef appExercise8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        SaveMenu                     matlab.ui.container.Menu
        SaveAsMenu                   matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        KeyboardshortcutsMenu        matlab.ui.container.Menu
        OpenoMenu                    matlab.ui.container.Menu
        PlotpMenu                    matlab.ui.container.Menu
        LowpasslMenu                 matlab.ui.container.Menu
        HighpasshMenu                matlab.ui.container.Menu
        BandpassmMenu                matlab.ui.container.Menu
        BandstopnMenu                matlab.ui.container.Menu
        ApplyaMenu                   matlab.ui.container.Menu
        SavesMenu                    matlab.ui.container.Menu
        CustomButton                 matlab.ui.control.Button
        APPLYButton                  matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        PLOTButton                   matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        SelectedsignalsListBox       matlab.ui.control.ListBox
        SelectedsignalsListBoxLabel  matlab.ui.control.Label
        AllsignalsListBox            matlab.ui.control.ListBox
        AllsignalsListBoxLabel       matlab.ui.control.Label
        BandstopButton               matlab.ui.control.Button
        BandpassButton               matlab.ui.control.Button
        LowpassButton                matlab.ui.control.Button
        HighpassButton               matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sigMain % Description
        saveFile = ""; % Description
        headerInfo % Description
        sampFreq % Description
        savePath = "" % Description
        NumDataRecords
        NumSignals
        PhysicalMin
        PhysicalMax
        DigitalMin
        DigitalMax
        filteredSig % Description
    end
    
    methods (Access = private)
        
        function plotAll(app, ax, sigs)
            cla(ax);
            data = app.SelectedsignalsListBox.ItemsData;
            names = app.SelectedsignalsListBox.Items;
            hold(ax,"on");
            for i = 1:length(data)
                plot(ax, sigs(str2double(data{i}), :));
            end
            legend(ax, names, 'Location','best');
            hold(ax, 'off');
        end
        
        function loadData (app)
            [file, path] = uigetfile('*.edf',"select a record");
            if isequal(file,0)
                disp("User selected Cancel");
                return;
            else
                disp(['User selected ', fullfile(path,file)]);
            end 
            oldPath = pwd;
            cd (path);
            [sigs,freg,tm]=rdsamp(file);
            app.sampFreq = freg;
            hdr = edfinfo(file);
            app.NumSignals = hdr.NumSignals;
            app.NumDataRecords = hdr.NumDataRecords;
            app.PhysicalMin = hdr.PhysicalMin.';
            app.PhysicalMax = hdr.PhysicalMax.';
            app.DigitalMin = hdr.DigitalMin.';
            app.DigitalMax = hdr.DigitalMax.';
            cd (oldPath);
            sigs = sigs.';
            sigs = sigs(1:64, :);
            app.sigMain = sigs;
            app.APPLYButton.Enable = 'off';
            app.LowpassButton.Enable = 'off';
            app.HighpassButton.Enable = 'off';
            app.BandpassButton.Enable = 'off';
            app.BandstopButton.Enable = 'off';
            app.CustomButton.Enable = 'off';
        end
        
        function saveData (app, force)
            curSaveFile = app.saveFile;
            curSavePath = app.savePath;
            if curSaveFile == "" || force
                [file, path] = uiputfile('*.edf',"select a record");
                if isequal(file,0)
                    disp("User selected Cancel");
                    return;
                else
                    disp(['User selected ', fullfile(path,file)]);
                end
                curSaveFile = file;
                curSavePath = path;
                app.saveFile = file;
                app.savePath = path;
            end
            oldPath = pwd;
            cd (curSavePath);
            data = app.SelectedsignalsListBox.ItemsData;
            sigs = app.sigMain.';
            hdr = edfheader("EDF");
            hdr.NumSignals = app.NumSignals;
            hdr.NumDataRecords = app.NumDataRecords;
            hdr.PhysicalMin = app.PhysicalMin;
            hdr.PhysicalMax = app.PhysicalMax;
            hdr.DigitalMin = app.DigitalMin;
            hdr.DigitalMax = app.DigitalMax;
            edfw = edfwrite(curSaveFile,hdr,sigs);
            cd (oldPath);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HighpassButton
        function HighpassButtonPushed(app, event)
            prompt = {'Enter "n" degree of filter:', 'Enter cutoff frequency: '};
            digtitle = 'Highpass filter';
            dims = [1 35];
            definput = {'100', '30'};
            answer = inputdlg(prompt, digtitle, dims, definput);
            if isempty(answer)
                return;
            end
            filterDeg = str2num(answer{1});
            filterCut = str2num(answer{2});
            flr = fir1(filterDeg, filterCut / (app.sampFreq/2), "high");
            data = str2double([app.SelectedsignalsListBox.ItemsData{:}]);
            sigs = [];
            length(app.sigMain)
            for i=1:64
                if ismember(i, data)
                    curFilter = filter(flr, 1, app.sigMain(data(find(data == i)), :));
                    sigs = [sigs; curFilter];
                else
                    sigs = [sigs; app.sigMain(i, :)];
                end
            end
            app.filteredSig = sigs;
            plotAll(app, app.UIAxes2, sigs);
            app.APPLYButton.Enable = 'on';
        end

        % Button pushed function: LowpassButton
        function LowpassButtonPushed(app, event)
            prompt = {'Enter "n" degree of filter:', 'Enter cutoff frequency: '};
            digtitle = 'Lowpass filter';
            dims = [1 35];
            definput = {'100', '8'};
            answer = inputdlg(prompt, digtitle, dims, definput);
            if isempty(answer)
                return;
            end
            filterDeg = str2num(answer{1});
            filterCut = str2num(answer{2});
            flr = fir1(filterDeg, filterCut / (app.sampFreq/2), "low");
            data = str2double([app.SelectedsignalsListBox.ItemsData{:}]);
            sigs = [];
            length(app.sigMain)
            for i=1:64
                if ismember(i, data)
                    curFilter = filter(flr, 1, app.sigMain(data(find(data == i)), :));
                    sigs = [sigs; curFilter];
                else
                    sigs = [sigs; app.sigMain(i, :)];
                end
            end
            app.filteredSig = sigs;
            plotAll(app, app.UIAxes2, sigs);
            app.APPLYButton.Enable = 'on';
        end

        % Double-clicked callback: AllsignalsListBox
        function AllsignalsListBoxDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            [~,idx] = ismember(app.AllsignalsListBox.Items(item) ,app.SelectedsignalsListBox.Items);
            if idx == 0
                app.SelectedsignalsListBox.Items(end+1) = convertStringsToChars(app.AllsignalsListBox.Items(item));
                app.SelectedsignalsListBox.ItemsData{end+1} = app.AllsignalsListBox.ItemsData(item);
            end
        end

        % Double-clicked callback: SelectedsignalsListBox
        function SelectedsignalsListBoxDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            app.SelectedsignalsListBox.Items(item) = [];
            app.SelectedsignalsListBox.ItemsData(item) = [];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.SelectedsignalsListBox.Items = {};
            app.SelectedsignalsListBox.ItemsData = {};
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            loadData(app);
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            if isempty(app.sigMain)
                loadData(app);
            end
            plotAll(app, app.UIAxes, app.sigMain);
            app.LowpassButton.Enable = 'on';
            app.HighpassButton.Enable = 'on';
            app.BandpassButton.Enable = 'on';
            app.BandstopButton.Enable = 'on';
            app.CustomButton.Enable = 'on';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.SelectedsignalsListBox.Items = app.AllsignalsListBox.Items(:);
            app.SelectedsignalsListBox.ItemsData = app.AllsignalsListBox.ItemsData(:);
        end

        % Button pushed function: BandpassButton
        function BandpassButtonPushed(app, event)
            prompt = {'Enter "n" degree of filter:', 'Enter lower frequency: ', 'Enter upper frequency: '};
            digtitle = 'Bandpass filter';
            dims = [1 35];
            definput = {'100', '8', '30'};
            answer = inputdlg(prompt, digtitle, dims, definput);
            if isempty(answer)
                return;
            end
            filterDeg = str2num(answer{1});
            filterCut1 = str2num(answer{2});
            filterCut2 = str2num(answer{3});
            flr = fir1(filterDeg, [filterCut1 filterCut2] / (app.sampFreq/2), "bandpass");
            data = str2double([app.SelectedsignalsListBox.ItemsData{:}]);
            sigs = [];
            length(app.sigMain)
            for i=1:64
                if ismember(i, data)
                    curFilter = filter(flr, 1, app.sigMain(data(find(data == i)), :));
                    sigs = [sigs; curFilter];
                else
                    sigs = [sigs; app.sigMain(i, :)];
                end
            end
            app.filteredSig = sigs;
            plotAll(app, app.UIAxes2, sigs);
            app.APPLYButton.Enable = 'on';
        end

        % Button pushed function: BandstopButton
        function BandstopButtonPushed(app, event)
            prompt = {'Enter "n" degree of filter:', 'Enter lower frequency: ', 'Enter upper frequency: '};
            digtitle = 'Bandstop filter';
            dims = [1 35];
            definput = {'100', '8', '30'};
            answer = inputdlg(prompt, digtitle, dims, definput);
            if isempty(answer)
                return;
            end
            filterDeg = str2num(answer{1});
            filterCut1 = str2num(answer{2});
            filterCut2 = str2num(answer{3});
            flr = fir1(filterDeg, [filterCut1 filterCut2] / (app.sampFreq/2), "stop");
            data = str2double([app.SelectedsignalsListBox.ItemsData{:}]);
            sigs = [];
            length(app.sigMain)
            for i=1:64
                if ismember(i, data)
                    curFilter = filter(flr, 1, app.sigMain(data(find(data == i)), :));
                    sigs = [sigs; curFilter];
                else
                    sigs = [sigs; app.sigMain(i, :)];
                end
            end
            app.filteredSig = sigs;
            plotAll(app, app.UIAxes2, sigs);
            app.APPLYButton.Enable = 'on';
        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)
            app.sigMain = app.filteredSig;
            cla(app.UIAxes2);
            plotAll(app, app.UIAxes, app.sigMain);
            app.APPLYButton.Enable = 'off';
        end

        % Button pushed function: CustomButton
        function CustomButtonPushed(app, event)
            prompt = {'Enter "a" coefficients:', 'Enter "b" coefficients:'};
            digtitle = 'Custom filter';
            dims = [1 35];
            definput = {'1','1 1'};
            answer = inputdlg(prompt, digtitle, dims, definput);
            if isequal(answer,0)
                return;
            end
            filterA = str2num(answer{1});
            filterB = str2num(answer{2});
            data = str2double([app.SelectedsignalsListBox.ItemsData{:}]);
            sigs = [];
            length(app.sigMain)
            for i=1:64
                if ismember(i, data)
                    curFilter = filter(filterB, filterA, app.sigMain(data(find(data == i)), :));
                    sigs = [sigs; curFilter];
                else
                    sigs = [sigs; app.sigMain(i, :)];
                end
            end
            app.filteredSig = sigs;
            plotAll(app, app.UIAxes2, sigs);
            app.APPLYButton.Enable = 'on';
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            saveData(app, false);
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            saveData(app, true);
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
            case 'o'
                OpenMenuSelected(app, "a");
            case 's'
                saveData(app, false);
            case 'S'
                saveData(app, true);
            case 'p'
                PLOTButtonPushed(app, "a");
            case 'a'
                APPLYButtonPushed(app, "a");
                app.APPLYButton.Enable = 'off';
            case 'l'
                LowpassButtonPushed(app, "a");
                app.APPLYButton.Enable = 'on';
            case 'h'
                HighpassButtonPushed(app, "a");
                app.APPLYButton.Enable = 'on';
            case 'm'
                BandpassButtonPushed(app, "a");
                app.APPLYButton.Enable = 'on';
            case 'n'
                BandstopButtonPushed(app, "a");
                app.APPLYButton.Enable = 'on';
            case 'c'
                CustomButtonPushed(app, "a");
                app.APPLYButton.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.KeyboardshortcutsMenu = uimenu(app.HelpMenu);
            app.KeyboardshortcutsMenu.Text = 'Keyboard shortcuts';
            app.OpenoMenu = uimenu(app.KeyboardshortcutsMenu);
            app.OpenoMenu.Text = 'Open = o';
            app.PlotpMenu = uimenu(app.KeyboardshortcutsMenu);
            app.PlotpMenu.Text = 'Plot = p';
            app.LowpasslMenu = uimenu(app.KeyboardshortcutsMenu);
            app.LowpasslMenu.Text = 'Low pass = l';
            app.HighpasshMenu = uimenu(app.KeyboardshortcutsMenu);
            app.HighpasshMenu.Text = 'High pass = h';
            app.BandpassmMenu = uimenu(app.KeyboardshortcutsMenu);
            app.BandpassmMenu.Text = 'Band pass = m';
            app.BandstopnMenu = uimenu(app.KeyboardshortcutsMenu);
            app.BandstopnMenu.Text = 'Band stop = n';
            app.ApplyaMenu = uimenu(app.KeyboardshortcutsMenu);
            app.ApplyaMenu.Text = 'Apply = a';
            app.SavesMenu = uimenu(app.KeyboardshortcutsMenu);
            app.SavesMenu.Text = 'Save = s';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Current')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Filtered')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.HighpassButton = uibutton(app.UIFigure, 'push');
            app.HighpassButton.ButtonPushedFcn = createCallbackFcn(app, @HighpassButtonPushed, true);
            app.HighpassButton.Enable = 'off';
            app.HighpassButton.Text = 'High pass';
            app.LowpassButton = uibutton(app.UIFigure, 'push');
            app.LowpassButton.ButtonPushedFcn = createCallbackFcn(app, @LowpassButtonPushed, true);
            app.LowpassButton.Enable = 'off';
            app.LowpassButton.Text = 'Low pass';
            app.BandpassButton = uibutton(app.UIFigure, 'push');
            app.BandpassButton.ButtonPushedFcn = createCallbackFcn(app, @BandpassButtonPushed, true);
            app.BandpassButton.Enable = 'off';
            app.BandpassButton.Text = 'Band pass';
            app.BandstopButton = uibutton(app.UIFigure, 'push');
            app.BandstopButton.ButtonPushedFcn = createCallbackFcn(app, @BandstopButtonPushed, true);
            app.BandstopButton.Enable = 'off';
            app.BandstopButton.Text = 'Band stop';
            app.AllsignalsListBoxLabel = uilabel(app.UIFigure);
            app.AllsignalsListBoxLabel.Text = 'All signals:';
            app.AllsignalsListBox = uilistbox(app.UIFigure);
            app.AllsignalsListBox.Items = {'Signal 1', 'Signal 2', 'Signal 3', 'Signal 4', 'Signal 5', 'Signal 6', 'Signal 7', 'Signal 8', 'Signal 9', 'Signal 10', 'Signal 11', 'Signal 12', 'Signal 13', 'Signal 14', 'Signal 15', 'Signal 16', 'Signal 17', 'Signal 18', 'Signal 19', 'Signal 20', 'Signal 21', 'Signal 22', 'Signal 23', 'Signal 24', 'Signal 25', 'Signal 26', 'Signal 27', 'Signal 28', 'Signal 29', 'Signal 30', 'Signal 31', 'Signal 32', 'Signal 33', 'Signal 34', 'Signal 35', 'Signal 36', 'Signal 37', 'Signal 38', 'Signal 39', 'Signal 40', 'Signal 41', 'Signal 42', 'Signal 43', 'Signal 44', 'Signal 45', 'Signal 46', 'Signal 47', 'Signal 48', 'Signal 49', 'Signal 50', 'Signal 51', 'Signal 52', 'Signal 53', 'Signal 54', 'Signal 55', 'Signal 56', 'Signal 57', 'Signal 58', 'Signal 59', 'Signal 60', 'Signal 61', 'Signal 62', 'Signal 63', 'Signal 64'};
            app.AllsignalsListBox.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60', '61', '62', '63', '64'};
            app.AllsignalsListBox.Multiselect = 'on';
            app.AllsignalsListBox.DoubleClickedFcn = createCallbackFcn(app, @AllsignalsListBoxDoubleClicked, true);
            app.AllsignalsListBox.Value = {'1'};
            app.SelectedsignalsListBoxLabel = uilabel(app.UIFigure);
            app.SelectedsignalsListBoxLabel.Text = 'Selected signals:';
            app.SelectedsignalsListBox = uilistbox(app.UIFigure);
            app.SelectedsignalsListBox.Items = {};
            app.SelectedsignalsListBox.Multiselect = 'on';
            app.SelectedsignalsListBox.DoubleClickedFcn = createCallbackFcn(app, @SelectedsignalsListBoxDoubleClicked, true);
            app.SelectedsignalsListBox.Value = {};
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '<<';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>>';
            app.APPLYButton = uibutton(app.UIFigure, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Enable = 'off';
            app.APPLYButton.Text = 'APPLY';
            app.CustomButton = uibutton(app.UIFigure, 'push');
            app.CustomButton.ButtonPushedFcn = createCallbackFcn(app, @CustomButtonPushed, true);
            app.CustomButton.Enable = 'off';
            app.CustomButton.Text = 'Custom';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appExercise8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end